classdef DIP_sjq < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        PSNR2           matlab.ui.control.TextArea
        PSNRLabel_2     matlab.ui.control.Label
        NC              matlab.ui.control.TextArea
        NCLabel         matlab.ui.control.Label
        Label_3         matlab.ui.control.Label
        PSNR1           matlab.ui.control.TextArea
        PSNRLabel       matlab.ui.control.Label
        PSNR_NC         matlab.ui.control.Button
        Rotate          matlab.ui.control.Button
        Cut             matlab.ui.control.Button
        Oppen_WM        matlab.ui.control.Button
        ExtractWm       matlab.ui.control.Button
        Oppen_2         matlab.ui.control.Button
        Label_2         matlab.ui.control.Label
        Label           matlab.ui.control.Label
        JunzhiFilter    matlab.ui.control.Button
        ZhongzhiFilter  matlab.ui.control.Button
        Save            matlab.ui.control.Button
        JiaoyanNoise    matlab.ui.control.Button
        PosongNoise     matlab.ui.control.Button
        LowpassFilter   matlab.ui.control.Button
        Clear           matlab.ui.control.Button
        GaosiNoise      matlab.ui.control.Button
        Exit            matlab.ui.control.Button
        Oppen           matlab.ui.control.Button
        NewWm           matlab.ui.control.UIAxes
        Attack          matlab.ui.control.UIAxes
        New             matlab.ui.control.UIAxes
        Watermarking    matlab.ui.control.UIAxes
        Original        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        file % Description
        lastimg % Description
        I % Description
        J % Description
        Wm % Description
        attack % Description
        i % Description
        j % Description
        S1 % Description
        U % Description
        V % Description
        alpha % Description
        n % Description
        h % Description
        w % Description
        a % Description
        b % Description
        IG % Description
        newwm % Description
    end
    
    properties (Access = private)
        img % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Exit
        function ExitPushed(app, event)
            choice=questdlg("是否要退出程序？","关闭","Yes","No","No");
            switch choice
                case "Yes"
                    delete(app.UIFigure);
                    return;
                case "No"
                    return;
            end
        end

        % Button pushed function: Oppen
        function OppenPushed(app, event)
            [filename, path]=uigetfile({'*.jpg';'*.bmp';'*.jpeg';'*.png'}, '打开文件');%uigetfile图像用户界面模块
            if isequal(filename,0) || isequal(path,0)
                errordlg("没有选中文件","错误");
            else
                app.file=strcat(path,filename);
            end
            %原图
            app.img=imread(app.file);
            app.I=imread(app.file);
            imshow(app.img,'Parent',app.Original);
        end

        % Button pushed function: GaosiNoise
        function GaosiNoisePushed(app, event)
            app.attack = imnoise(app.Wm,'gaussian',0,0.01);
            imshow(app.attack,'Parent',app.Attack);
        end

        % Button pushed function: ZhongzhiFilter
        function ZhongzhiFilterButtonPushed(app, event)
            % r=medfilt2(app.img(:,:,1));%执行二维中值滤波
            % g=medfilt2(app.img(:,:,2));
            % b=medfilt2(app.img(:,:,3));
            % zzimg=cat(3,r,g,b);%cat构造三维数组
            app.attack=medfilt2(app.Wm);
            imshow(app.attack,'Parent',app.Attack);
        end

        % Button pushed function: JunzhiFilter
        function JunzhiFilterButtonPushed(app, event)
            h=fspecial('average',3);
            app.attack=imfilter(app.attack,h,'replicate');
            imshow(app.attack,'Parent',app.Attack);
        end

        % Button pushed function: Clear
        function ClearButtonPushed(app, event)
            app.img=imread(app.file);
            imshow(app.img,'Parent',app.New);
        end

        % Button down function: Original
        function OriginalButtonDown(app, event)
            
        end

        % Callback function
        function DCTButtonPushed(app, event)
            % 将图像转换为灰度图像（如果原始图像不是灰度图像）
            gray_image = rgb2gray(app.img);
            % 执行二维 DCT 变换
            dct_image = dct2(double(gray_image));
            imshow(log(abs(dct_image)), 'Parent',app.NewWmq1);
            app.lastimg=dct_image;
        end

        % Button pushed function: PosongNoise
        function PosongNoiseButtonPushed(app, event)
            app.attack = imnoise(app.attack,'poisson');
            imshow(app.attack,'Parent',app.Attack);
        end

        % Button pushed function: JiaoyanNoise
        function JiaoyanNoiseButtonPushed(app, event)
            app.attack = imnoise(app.attack,'salt & pepper',0.02);
            imshow(app.attack,'Parent',app.Attack);
        end

        % Button pushed function: Save
        function SaveButtonPushed(app, event)
            [lastfilename, lastpath]= uiputfile('*.jpg','Save Image as');
            save=strcat(lastpath,lastfilename);
            imwrite(app.img,save,'tif');
        end

        % Button pushed function: Oppen_2
        function Oppen_2ButtonPushed(app, event)
            % size=512; N=32;K=8; D=zeros(size);E=0.01;
            % % %原图
            % I=double(app.I)/512;
            % %对水印缩放
            % J=imresize(app.J,[64,64]);
            % 
            % %嵌入水印
            % for p=1:size/K %为待嵌入水印的变换域的行坐标
            %     for q=1:size/K
            %         x=(p-1)*K+1; y=(q-1)*K+1;
            %         I_dct=I(x:x+K-1,y:y+K-1);%将原图分成8*8的子块
            %         I_dct1=dct2(I_dct);%对子块进行DCT变换
            %         if J(p,q)==0
            %             alfa=-1;
            %         else
            %             alfa=1;
            %         end
            %         I_dct2=I_dct1+alfa*E;
            %         I_dct=idct2(I_dct2);
            %         D(x:x+K-1,y:y+K-1)=I_dct;
            %     end
            % end
            % app.Wm=uint8(D*512);
            % app.attack=app.Wm;
            % imshow(app.Wm,'Parent',app.New);
            M = 512; % 原图像长度
            N = 64; % 水印图像长度

            app.alpha=0.05;% 嵌入强度系数

            % 打开原图、水印图
            I = app.I;
            app.IG = app.J;
            G = app.IG;
            img=G;%读取图片，根据Arnold变换的条件，图像必须是正方形
            mysize=size(img);%当只有一个输出参数时，返回一个行向量，该行向量的第一个元素时矩阵的行数，第二个元素是矩阵的列数。

            %置乱与逆置乱必须采用共同参数的时候,就相当于密码，才能解密，让图像复原
            app.n=10;%迭代次数
            app.a=1;app.b=1;
            a = app.a;
            b = app.b;

            %置乱操作
            app.h = N;
            app.w = N;
            imgnnn=zeros(app.h,app.w);
            for i=1:app.n
                for y=1:app.h
                    for x=1:app.w
                        xx=mod((x-1)+b*(y-1),N)+1;   %mod取余操作，(a,b)就是a除以b的余数
                        yy=mod(a*(x-1)+(a*b+1)*(y-1),N)+1;
                        imgnnn(yy,xx)=img(y,x);
                    end
                end
                img=imgnnn;
            end
            imgnnn = uint8(imgnnn);

            % imwrite (imgnnn,'置乱后的图片.bmp');  %生成图像保存功能

            G=imgnnn;
            G = double(G) * 255; % 将逻辑值 1 转换为 255

            %Step 1
            %对G进行SVD分解
            [app.U,S,app.V] = svd(G);% 对G进行SVD分解，得到U、S、V矩阵

            %Step 2
            %进行2级离散小波变换
            [LL1, LH1, HL1, HH1] = dwt2(I, 'haar');
            [LL2, LH2, HL2, HH2] = dwt2(LL1, 'haar');%128*128

            %Step 3
            %选出最优嵌入块
            img=HH2;
            % 初始化变量
            img_size = [128 128];
            sub_size = 64;
            max_entropy = -Inf;
            max_entropy_pos = [0, 0];
            max_entropy_sub = [];

            % 遍历图像中的所有64×64子片段
            for i = 1:img_size(1) - sub_size + 1
                for j = 1:img_size(2) - sub_size + 1
                    % 提取子片段
                    sub_img = img(i:i+sub_size-1, j:j+sub_size-1);

                    % 计算子片段的信息熵
                    current_entropy = entropy(sub_img);

                    % 更新最大信息熵及其位置
                    if current_entropy > max_entropy
                        max_entropy = current_entropy;
                        max_entropy_pos = [i, j];
                        max_entropy_sub = sub_img;
                    end
                end
            end

            app.i = max_entropy_pos(1);
            app.j = max_entropy_pos(2);

            H_I = HH2(app.i:app.i+sub_size-1, app.j:app.j+sub_size-1);
            B = dct2(H_I);

            %Step 5
            %对B进行奇异值分解,嵌入水印
            [U1,app.S1,V1] = svd(B);
            S1 = app.S1;
            S2 = S1 + app.alpha * S;
            B1 = U1 * S2 * V1;
            H_I = idct2(B1);
            HH2(app.i:app.i+sub_size-1, app.j:app.j+sub_size-1) = H_I;
            LL1 = idwt2(LL2,LH2,HL2,HH2,'haar');
            W = idwt2(LL1,LH1,HL1,HH1,'haar');
            W = uint8(W);
            aW = W;
            app.Wm=W;
            app.attack=app.Wm;
            imshow(app.Wm,'Parent',app.New);
        end

        % Button pushed function: Oppen_WM
        function Oppen_WMButtonPushed(app, event)
            [filename, path]=uigetfile({'*.jpg';'*.bmp';'*.jpeg';'*.png'}, '打开文件');%uigetfile图像用户界面模块
            if isequal(filename,0) || isequal(path,0)
                errordlg("没有选中文件","错误");
            else
                app.file=strcat(path,filename);
            end
            %图
            app.J=imread(app.file);
            imshow(app.J,'Parent',app.Watermarking);
        end

        % Button pushed function: ExtractWm
        function ExtractWmButtonPushed(app, event)
            % % 提取水印
            % size=512; N=32;K=8; D=zeros(size);E=0.01;
            % I=double(app.I)/512;
            % QQ=double(app.attack)/512;
            % for p=1:size/K
            %     for q=1:size/K
            %         x=(p-1)*K+1;
            %         y=(q-1)*K+1;
            %         I1=I(x:x+K-1,y:y+K-1);
            %         I2=QQ(x:x+K-1,y:y+K-1);
            %         I_dct1=dct2(I1);
            %         I_dct2=dct2(I2);
            %         if I_dct2>I_dct1
            %             W(p,q)=1;
            %         else
            %             W(p,q)=0;
            %         end
            %     end
            % end
            % imshow(W,'Parent',app.NewWmq1);
            % 读取图像
            refImg = app.I;
            rotatedImg =app.attack;

            % 检测SURF特征点
            refPoints = detectSURFFeatures(refImg);
            rotatedPoints = detectSURFFeatures(rotatedImg);

            % 提取特征描述子
            [refFeatures, refValidPoints] = extractFeatures(refImg, refPoints);
            [rotFeatures, rotValidPoints] = extractFeatures(rotatedImg, rotatedPoints);

            % 特征匹配
            indexPairs = matchFeatures(refFeatures, rotFeatures, 'Unique', true);

            % 获取匹配点坐标
            matchedRef = refValidPoints(indexPairs(:,1));
            matchedRot = rotValidPoints(indexPairs(:,2));

            % 计算旋转角度（RANSAC剔除异常值）
            [tform, inlierIdx] = estimateGeometricTransform2D(...
                matchedRot.Location, matchedRef.Location, 'similarity');

            % 提取旋转角度
            rotationMatrix = tform.T(1:2,1:2);
            estimatedAngle = atan2d(rotationMatrix(2,1), rotationMatrix(1,1));

            % % 校正图像
            % outputView = imref2d(size(refImg));
            % correctedImg = imwarp(rotatedImg, tform, 'OutputView', outputView);
            app.attack = imrotate(app.attack, estimatedAngle, 'bilinear', 'crop');

            %提取水印
            a = app.a;
            b = app.b;
            [LL3, LH3, HL3, HH3] = dwt2(app.attack, 'haar');
            [LL4, LH4, HL4, HH4] = dwt2(LL3, 'haar');%128*128
            H_I2 = HH4(app.i:app.i+64-1, app.j:app.j+64-1);
            B2 = dct2(H_I2);
            [Uw,Sw,Vw] = svd(B2);
            S1 = app.S1;
            Sx = (Sw - S1)/app.alpha;
            B2 = app.U * Sx * app.V.';
            A = B2;

            %逆置乱复原
            img2=A;%读取置乱图片
            for i=1:app.n
                for y=1:app.h
                    for x=1:app.w
                        xx=mod((a*b+1)*(x-1)-b*(y-1),64)+1;%matlab索引中不能是0，要从1开始
                        yy=mod(-a*(x-1)+(y-1),64)+1  ;
                        imgnnn(yy,xx)=img2(y,x);
                    end
                end
                img2=imgnnn;
            end
            imgnnn = uint8(imgnnn);
            app.newwm = imgnnn;
            imshow(app.newwm,[],'Parent',app.NewWm);
        end

        % Button pushed function: LowpassFilter
        function LowpassFilterButtonPushed(app, event)

            h=fspecial('gaussian',3,0.4 );
            app.attack=imfilter(app.attack,h);
            imshow(app.attack,[],'Parent',app.Attack);
        end

        % Button pushed function: Cut
        function CutButtonPushed(app, event)
            % 剪切攻击
            % function cropped_image = edgeattack(img, percentage)
            %     row = size(img, 1);
            %     col = size(img, 2);
            %     row_half = round(round(row * percentage) / 2);
            %     col_half = round(round(col * percentage) / 2);
            %     % 将图像四条边缘条值置为0,即显示为黑色
            %     img(1:row_half, :, :) = 0;
            %     img(:, 1:col_half, :) = 0;
            %     img((row - row_half):row, :, :) = 0;
            %     img(:, (col - col_half):col, :) = 0;
            %     cropped_image = img;
            % end
            % percentage = 0.2; % 剪切比例
            % app.attack = edgeattack(app.Wm, percentage);
            % imshow(app.attack,[],'Parent',app.Attack);
            
            r = 0.8; % 剪切比例为30%
            sz = size(app.attack);
            h1 = round(sz(1)*r); % 剪切高度
            w1 = round(sz(2)*r); % 剪切宽度
            x1 = round(rand(sz(1)-h1)); % 随机选择一行
            y1 = round(rand(sz(2)-w1)); % 随机选择一列
            % x1=300;
            % y1=300;
            app.attack(x1+1:x1+h1, y1+1:y1+w1) = 0; % 将指定区域置为0
            imshow(app.attack,[],'Parent',app.Attack);
        end

        % Button pushed function: Rotate
        function RotateButtonPushed(app, event)
            % 旋转攻击
            angle = 10; % 旋转角度
            app.attack = imrotate(app.Wm, angle, 'bicubic', 'crop');
            imshow(app.attack,[],'Parent',app.Attack);
        end

        % Button pushed function: PSNR_NC
        function PSNR_NCButtonPushed(app, event)

            % 计算PSNR值
            psnr_val = psnr(app.I, app.Wm);
            app.PSNR1.Value = num2str(psnr_val);

            % 计算SSIM值
            [ssim_val,~] = ssim(app.I, app.Wm);
            app.PSNR2.Value = num2str(ssim_val);

            % 计算NC值
            % 提取最大相关系数值
            nc_val = corr2(app.IG, app.newwm);
            % 输出结果
            app.NC.Value = num2str(nc_val);

             if psnr_val >= 40 && ssim_val >= 0.95
                 uialert(app.UIFigure, 'PSNR值与SSIM值计算成功，水印不可见性良好', '提示','Icon','success','Modal',false);
             elseif psnr_val > 30 || ssim_val > 0.9 
                 uialert(app.UIFigure, 'PSNR值与SSIM值计算成功，水印不可见性一般', '提示','Icon','warning','Modal',false);
             else 
                 uialert(app.UIFigure, 'PSNR值与SSIM值计算成功，水印不可见性不足', '提示','Icon','error','Modal',false);
             end

             if nc_val >= 0.95
                 uialert(app.UIFigure, 'NC值计算成功，水印鲁棒性良好', '提示','Icon','success','Modal',false);
             elseif nc_val > 0.85 
                 uialert(app.UIFigure, 'NC值计算成功，水印鲁棒性一般', '提示','Icon','warning','Modal',false);
             else 
                 uialert(app.UIFigure, 'NC值计算成功，水印鲁棒性不足', '提示','Icon','error','Modal',false);
             end

    %     % 创建绿色指示（假设有指示灯组件叫Indicator）
    %     app.Indicator.FillColor = [0.47 0.67 0.19]; % MATLAB绿色
    % 
    %     % 弹窗提示
    %     uialert(app.UIFigure,...
    %         '计算成功，水印不可见性较好',...
    %         '结果提示',...
    %         'Icon','success',...
    %         'CloseFcn',@(src,event)[]);
    % else
    %     % 可选：设置其他颜色（如果需要）
    %     app.Indicator.FillColor = [1 0 0]; % 红色

    % uialert( '操作已完成', '提示');
    
    % success'、'error'、'warning'、'info'、'question'
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Original = uiaxes(app.UIFigure);
            title(app.Original, '原图')
            zlabel(app.Original, 'Z')
            app.Original.ButtonDownFcn = createCallbackFcn(app, @OriginalButtonDown, true);
            app.Watermarking = uiaxes(app.UIFigure);
            title(app.Watermarking, '水印')
            zlabel(app.Watermarking, 'Z')
            app.New = uiaxes(app.UIFigure);
            title(app.New, '含水印图')
            zlabel(app.New, 'Z')
            app.Attack = uiaxes(app.UIFigure);
            title(app.Attack, '被攻击后的图')
            zlabel(app.Attack, 'Z')
            app.NewWm = uiaxes(app.UIFigure);
            title(app.NewWm, '提取的水印')
            zlabel(app.NewWm, 'Z')
            app.Oppen = uibutton(app.UIFigure, 'push');
            app.Oppen.ButtonPushedFcn = createCallbackFcn(app, @OppenPushed, true);
            app.Oppen.Text = '打开原图';
            app.Exit = uibutton(app.UIFigure, 'push');
            app.Exit.ButtonPushedFcn = createCallbackFcn(app, @ExitPushed, true);
            app.Exit.Text = '退出';
            app.GaosiNoise = uibutton(app.UIFigure, 'push');
            app.GaosiNoise.ButtonPushedFcn = createCallbackFcn(app, @GaosiNoisePushed, true);
            app.GaosiNoise.Text = '高斯噪声';
            app.Clear = uibutton(app.UIFigure, 'push');
            app.Clear.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.Clear.Text = '还原';
            app.LowpassFilter = uibutton(app.UIFigure, 'push');
            app.LowpassFilter.ButtonPushedFcn = createCallbackFcn(app, @LowpassFilterButtonPushed, true);
            app.LowpassFilter.Text = '低通滤波';
            app.PosongNoise = uibutton(app.UIFigure, 'push');
            app.PosongNoise.ButtonPushedFcn = createCallbackFcn(app, @PosongNoiseButtonPushed, true);
            app.PosongNoise.Text = '泊松噪声';
            app.JiaoyanNoise = uibutton(app.UIFigure, 'push');
            app.JiaoyanNoise.ButtonPushedFcn = createCallbackFcn(app, @JiaoyanNoiseButtonPushed, true);
            app.JiaoyanNoise.Text = '椒盐噪声';
            app.Save = uibutton(app.UIFigure, 'push');
            app.Save.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.Save.Text = '保存';
            app.ZhongzhiFilter = uibutton(app.UIFigure, 'push');
            app.ZhongzhiFilter.ButtonPushedFcn = createCallbackFcn(app, @ZhongzhiFilterButtonPushed, true);
            app.ZhongzhiFilter.Text = '中值滤波';
            app.JunzhiFilter = uibutton(app.UIFigure, 'push');
            app.JunzhiFilter.ButtonPushedFcn = createCallbackFcn(app, @JunzhiFilterButtonPushed, true);
            app.JunzhiFilter.Text = '均值滤波';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '加噪';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '滤波';
            app.Oppen_2 = uibutton(app.UIFigure, 'push');
            app.Oppen_2.ButtonPushedFcn = createCallbackFcn(app, @Oppen_2ButtonPushed, true);
            app.Oppen_2.Text = '嵌入水印';
            app.ExtractWm = uibutton(app.UIFigure, 'push');
            app.ExtractWm.ButtonPushedFcn = createCallbackFcn(app, @ExtractWmButtonPushed, true);
            app.ExtractWm.Text = '提取水印';
            app.Oppen_WM = uibutton(app.UIFigure, 'push');
            app.Oppen_WM.ButtonPushedFcn = createCallbackFcn(app, @Oppen_WMButtonPushed, true);
            app.Oppen_WM.Text = '打开水印图';
            app.Cut = uibutton(app.UIFigure, 'push');
            app.Cut.ButtonPushedFcn = createCallbackFcn(app, @CutButtonPushed, true);
            app.Cut.Text = '剪切';
            app.Rotate = uibutton(app.UIFigure, 'push');
            app.Rotate.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.Rotate.Text = '旋转';
            app.PSNR_NC = uibutton(app.UIFigure, 'push');
            app.PSNR_NC.ButtonPushedFcn = createCallbackFcn(app, @PSNR_NCButtonPushed, true);
            app.PSNR_NC.Text = '计算评价指标';
            app.PSNRLabel = uilabel(app.UIFigure);
            app.PSNRLabel.Text = 'PSNR(原图与含水印图)';
            app.PSNR1 = uitextarea(app.UIFigure);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '其它';
            app.NCLabel = uilabel(app.UIFigure);
            app.NCLabel.Text = 'NC(原水印与提取水印)';
            app.NC = uitextarea(app.UIFigure);
            app.PSNRLabel_2 = uilabel(app.UIFigure);
            app.PSNRLabel_2.Text = 'SSIM(原图与含水印图)';
            app.PSNR2 = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DIP_sjq

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end